classdef visualizeAppReg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ComparetwosetstoolsTab         matlab.ui.container.Tab
        Dataset1SliderLabel            matlab.ui.control.Label
        Dataset1Slider                 matlab.ui.control.Slider
        Dataset2SliderLabel            matlab.ui.control.Label
        Dataset2Slider                 matlab.ui.control.Slider
        Dataset1Slider_2Label          matlab.ui.control.Label
        Dataset1Slider_2               matlab.ui.control.Slider
        Dataset2Slider_2Label          matlab.ui.control.Label
        Dataset2Slider_2               matlab.ui.control.Slider
        MarkersizeSliderLabel          matlab.ui.control.Label
        MarkersizeSlider               matlab.ui.control.Slider
        NumberofneuronsLogarithmicscaleLabel  matlab.ui.control.Label
        AbsolutevalueofregressorLabel  matlab.ui.control.Label
        CheckdensitiesofbothsetstoolsTab  matlab.ui.container.Tab
        DensitytoplotforButtonGroup    matlab.ui.container.ButtonGroup
        Dataset1Button                 matlab.ui.control.RadioButton
        Dataset2Button                 matlab.ui.control.RadioButton
        GaussianizeSliderLabel         matlab.ui.control.Label
        GaussianizeSlider              matlab.ui.control.Slider
        Quantileforisovalue1EditFieldLabel  matlab.ui.control.Label
        Quantileforisovalue1EditField  matlab.ui.control.NumericEditField
        Quantileforisovalue2EditFieldLabel  matlab.ui.control.Label
        Quantileforisovalue2EditField  matlab.ui.control.NumericEditField
        Quantileforisovalue3EditFieldLabel  matlab.ui.control.Label
        Quantileforisovalue3EditField  matlab.ui.control.NumericEditField
        Isovalue1EditFieldLabel        matlab.ui.control.Label
        Isovalue1EditField             matlab.ui.control.NumericEditField
        Isovalue2EditFieldLabel        matlab.ui.control.Label
        Isovalue2EditField             matlab.ui.control.NumericEditField
        Isovalue3EditFieldLabel        matlab.ui.control.Label
        Isovalue3EditField             matlab.ui.control.NumericEditField
        DFFregressorplottoolsTab       matlab.ui.container.Tab
        PlotbrainzonesTab              matlab.ui.container.Tab
        PlotscatteredoutlinesofzonesCheckBox  matlab.ui.control.CheckBox
        PlotXYcontoursofzonesCheckBox  matlab.ui.control.CheckBox
        ChangecolorsetButton           matlab.ui.control.Button
        PlotthefollowingbrainzonesListBoxLabel  matlab.ui.control.Label
        PlotthefollowingbrainzonesListBox  matlab.ui.control.ListBox
        presscontroltopickmultipleLabel  matlab.ui.control.Label
        ListBox                        matlab.ui.control.ListBox
        PlotwholebrainButton           matlab.ui.control.Button
        PlotYZcontoursofzonesCheckBox  matlab.ui.control.CheckBox
        PlotXZcontoursofzonesCheckBox  matlab.ui.control.CheckBox
        SamecolorButton                matlab.ui.control.Button
        LoadZBraingridobjectButton     matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        ListofcommentsDropDownLabel    matlab.ui.control.Label
        ListofcommentsDropDown         matlab.ui.control.DropDown
        ListofkeywordsDropDownLabel    matlab.ui.control.Label
        ListofkeywordsDropDown         matlab.ui.control.DropDown
        Keywordsfordataset1usecommastoseparateEditFieldLabel  matlab.ui.control.Label
        Keywordsfordataset1usecommastoseparateEditField  matlab.ui.control.EditField
        Keywordsfordataset2usecommastoseparateEditFieldLabel  matlab.ui.control.Label
        Keywordsfordataset2usecommastoseparateEditField  matlab.ui.control.EditField
        PleasechoserepresentationoptionLabel  matlab.ui.control.Label
        PleasechoserepresentationoptionListBox  matlab.ui.control.ListBox
        UIAxes                         matlab.ui.control.UIAxes
        ControlsPanel                  matlab.ui.container.Panel
        RotateButton                   matlab.ui.control.Button
        XZButton                       matlab.ui.control.Button
        XYButton                       matlab.ui.control.Button
        YZButton                       matlab.ui.control.Button
        ZoomButton                     matlab.ui.control.Button
        ReinitializeButton             matlab.ui.control.Button
    end


    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    properties (Access = public)
        
        % ZBraingrid objects, and related:
        zgrid % Data loaded through loading button, should be a ZBraingrid object
        zgridpath % Path to data, to be indicated in text zone
        regions % Brain regions to plot
        zoutlines % Outlines from MaskDatabase
        OutplotS % Outlines for main zones: scattered outlines
        OutplotC % Outlines for main zones: contours
        acolor % Color for outlines 
        
        % Information on the two subsets:
        search1 % Keywords for subset 1
        search2 % Keywords for subset 2
        zset1 % ZBraingrid object containing datasets selected with keywords 1
        zset2 % ZBraingrid object containing datasets selected with keywords 2
        nneu1 % Number of neurons for dataset 1
        nneu2 % Number of neurons for dataset 2
        zcobj1 % Sorted correlations vector for dataset 1
        zcobj2 % Sorted correlations vector for dataset 2
        maval1 % Maximum regressor absolute value for dataset 1
        maval2 % Maximum regressor absolute value for dataset 2
        
        % Plot options:
        ptool % Tool for plot, can be 1, 2, or 3
        mrksize % Marker size for plot options 1 and 3
        arotate % To rotate figure
        azoom % To zoom in figure
        aview % Rotation of plot
        alim % Limits for the axes
        
        % Plot options for isovalues:
        whichset % Which set to plot density
        gauss % Gaussianization parameter
        isoval1 % First isovalue
        isoval2 % Second isovalue
        isoval3 % Third isovalue
        
    end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    methods (Access = private)
    
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function updateIso(app)
            
            % Get set value from whichset:
            if app.whichset == 1
                zset = app.zset1;
            elseif app.whichset == 2
                zset = app.zset2;
            end
            
            % Update isovalue sliders:
            app.Quantileforisovalue1EditField.Value = 0.9;
            app.Quantileforisovalue2EditField.Value = 0.85;
            app.Quantileforisovalue3EditField.Value = 0.75;
            app.isoval1 = quantile(zset.Zcorrel, app.Quantileforisovalue1EditField.Value);
            app.isoval2 = quantile(zset.Zcorrel, app.Quantileforisovalue2EditField.Value);
            app.isoval3 = quantile(zset.Zcorrel, app.Quantileforisovalue3EditField.Value);
            app.Isovalue1EditField.Value = app.isoval1;
            app.Isovalue2EditField.Value = app.isoval2;
            app.Isovalue3EditField.Value = app.isoval3;
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function plotWrapper(app)
        % Function that launches automaticPlot corresponding to ptool.
        
            % Save view:
            app.aview = get(app.UIAxes, 'View');
            
            % IMPORTANT: CLEAR UIAXES
            cla(app.UIAxes)
        
            % Define if it is possible to plot:
            plotok = 0;
            checklen1 = (~isempty(app.zset1));
            checklen2 = (~isempty(app.zset2));
            if (app.ptool == 1 || app.ptool == 3) && (checklen1 && checklen2)
                plotok = 1;
            elseif app.ptool == 2 
                checkb1 = (checklen1 && app.Dataset1Button.Value == true);
                checkb2 = (checklen2 && app.Dataset2Button.Value == true);
                if (checkb1 || checkb2)
                    plotok = 1;
                end
            end
            
            % Plot outlines:%                 
            hold(app.UIAxes, 'on')
            if app.PlotscatteredoutlinesofzonesCheckBox.Value == true && ~isempty(app.regions)
                scatter3(app.UIAxes, app.OutplotS(:, 1), app.OutplotS(:, 2), app.OutplotS(:, 3), '.', 'MarkerFaceAlpha', 0.05,'MarkerEdgeAlpha', 0.05)
            end
            if app.PlotXYcontoursofzonesCheckBox.Value == true
                for i = 1:size(app.OutplotC, 1)
                    plot3(app.UIAxes, app.OutplotC{i, 1}(:, 1), app.OutplotC{i, 1}(:, 2), app.OutplotC{i, 1}(:, 3), 'Color', app.acolor(i, :), 'LineWidth', 2)
                end
            end
            if app.PlotYZcontoursofzonesCheckBox.Value == true
                for i = 1:size(app.OutplotC, 1)
                    plot3(app.UIAxes, app.OutplotC{i, 2}(:, 1), app.OutplotC{i, 2}(:, 2), app.OutplotC{i, 2}(:, 3), 'Color', app.acolor(i, :), 'LineWidth', 2)
                end
            end
            
            if app.PlotXZcontoursofzonesCheckBox.Value == true
                for i = 1:size(app.OutplotC, 1)
                    plot3(app.UIAxes, app.OutplotC{i, 3}(:, 1), app.OutplotC{i, 3}(:, 2), app.OutplotC{i, 3}(:, 3), 'Color', app.acolor(i, :), 'LineWidth', 2)
                end
            end
                    
            % Which plot:
            if plotok == 1
                if app.ptool == 1
                    automaticPlot1(app)
                elseif app.ptool == 2
                    automaticPlot2(app)
                elseif app.ptool == 3
                    automaticPlot3(app)
                end
            else
                msgbox('Unable to plot with current configuration. Please check that representation option is adapted to datasets loaded.')
                cla(app.UIAxes)
            end
            
            % Zoom and rotation:
            view(app.UIAxes, app.aview)
            if app.arotate == 1
                rotate3d(app.UIAxes,'on')
                zoom(app.UIAxes,'off')
            elseif app.azoom == 1
                rotate3d(app.UIAxes,'off')
                zoom(app.UIAxes,'on')
            else
                rotate3d(app.UIAxes,'off')
                zoom(app.UIAxes,'off')
            end   
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function newRegions(app)
        % Function that computes OutplotS and OutplotC whenever new zones are added.
            
            % Scattered outlines:
            xplot = (app.zoutlines.xgrid(2:end)' + app.zoutlines.xgrid(1:end-1)') / 2;
            yplot = (app.zoutlines.ygrid(2:end)' + app.zoutlines.ygrid(1:end-1)') / 2;
            zplot = (app.zoutlines.zgrid(2:end)' + app.zoutlines.zgrid(1:end-1)') / 2;
            tempOut = zeros(0, 3);
            for i = app.regions
                [xcoord, ycoord, zcoord] = ind2sub(app.zoutlines(i).gridsize(1:3), app.zoutlines(i).Zindex);
                tempOut = cat(1, tempOut, [xplot(xcoord), yplot(ycoord), zplot(zcoord)]);
            end
            app.OutplotS = unique(tempOut, 'rows');
            
            % Contours:
            tempOut = cell(length(app.regions), 3);
            for i = 1:length(app.regions)
                temp = zeros(app.zoutlines.gridsize(1:3));
                temp(app.zoutlines(app.regions(i)).Zindex) = 1;
                temp1 = sum(temp, 3) > 0;
                BW1 = bwboundaries(temp1, 'noholes');
                tempOut{i, 1} = [xplot(BW1{1}(:, 1)), yplot(BW1{1}(:, 2)), zplot(end)*ones(size(yplot(BW1{1}(:, 2))))];
                temp2 = permute(sum(temp, 1), [2, 3, 1]) > 0;
                BW2 = bwboundaries(temp2, 'noholes');
                tempOut{i, 2} = [xplot(1)*ones(size(yplot(BW2{1}(:, 1)))), yplot(BW2{1}(:, 1)), zplot(BW2{1}(:, 2))];
                temp3 = permute(sum(temp, 2), [1, 3, 2]) > 0;
                BW3 = bwboundaries(temp3, 'noholes');
                tempOut{i, 3} = [xplot(BW3{1}(:, 1)), yplot(end)*ones(size(xplot(BW3{1}(:, 1)))), zplot(BW3{1}(:, 2))];
            end
            app.OutplotC = tempOut;
            app.acolor = rand(length(app.regions), 3);
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
    end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add path to class:
            addpath(genpath('/home/ljp/Science/Hippolyte/multiSensorint'))
            
            % Initialize some values:
            app.arotate = 0;
            app.azoom = 0;
            app.aview = [-45, 45];
            app.alim = [0.496, 1.122, 0.276];
            app.mrksize = 5;
            app.ptool = 2;
            app.whichset = 1;
            app.gauss = 0.01;
            app.search1 = '';
            app.search2 = '';
            
            % Plotting parameters:
            app.UIAxes.DataAspectRatio = [1, 1, 1];
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.ZGrid = 'on';
            title(app.UIAxes, 'Figure for multi modal analysis')
            xlabel(app.UIAxes, 'x-axis', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'y-axis', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'z-axis', 'Interpreter', 'latex')
            app.UIAxes.XLim = [0, app.alim(1)];
            app.UIAxes.YLim = [0, app.alim(2)];
            app.UIAxes.ZLim = [0, app.alim(3)];
            view(app.UIAxes, app.aview)
            
            % Load MaskDatabase and build landmarks:
            load('zoutlines005.mat', 'zoutlines005');
            app.zoutlines = zoutlines005;
            app.OutplotS = [];
            app.OutplotC = {};
            app.regions = [];
            
            % Load brain regions names:
            load('MaskDatabaseNames.mat', 'MaskDatabaseNames');
            app.PlotthefollowingbrainzonesListBox.Items = {};
            for i = 1:length(MaskDatabaseNames)
                labtemp = num2str(i);
                if length(labtemp) == 1
                    labtemp = strcat('00', labtemp);
                elseif length(labtemp) == 2
                    labtemp = strcat('0', labtemp);
                end
                labtemp = strcat(labtemp, ':', MaskDatabaseNames{i});
                app.PlotthefollowingbrainzonesListBox.Items = [app.PlotthefollowingbrainzonesListBox.Items, labtemp];
            end
            app.ListBox.Items = {};
        end

        % Button pushed function: LoadZBraingridobjectButton
        function LoadZBraingridobjectButtonPushed(app, event)
            [file, path] = uigetfile;
            if ~endsWith(file, ".mat")
                msgbox('Imported data should be a .mat file.')
            elseif file ~= 0
                % Rebuild path:
                filepath = fullfile(path, file);
                % Get class:
                temp = load(char(filepath));
                field_temp = fields(temp);
                temp = temp.(field_temp{1});
                % Class should be ZBraingrid
                if class(temp) == "ZBraingrid"
                    % Private properties:
                    app.zgrid = temp;
                    app.zgridpath = filepath;
                    app.TextArea.Value = {filepath};
                    % Updating comments and keywords:
                    zgridlen = length(temp.comments);
                    unique_key = "";
                    ListofcommentsDropDown_temp = cell(1, zgridlen);
                    for i = 1:zgridlen
                        unique_key = unique_key + temp.comments(i);
                        if i ~= zgridlen
                            unique_key = unique_key + " + ";
                        end
                        ListofcommentsDropDown_temp{i} = char(temp.comments(i));
                    end
                    app.ListofcommentsDropDown.Items = ListofcommentsDropDown_temp;
                    unique_key = split(unique_key, " + ");
                    unique_key = unique(unique_key);
                    ListofkeywordsDropDown_temp = cell(1, length(unique_key));
                    for i = 1:length(unique_key)
                        ListofkeywordsDropDown_temp{i} = char(unique_key(i));
                    end
                    app.ListofkeywordsDropDown.Items = ListofkeywordsDropDown_temp;
                    msgbox('ZBraingrid successfully loaded!')
                else
                    msgbox('Data to load should be a ZBraingrid object.')
                end
            end
            
%             % Temporary script to get index of 5% most responsive neurons for each stimulus:
%             maxilabs = ["sine", "step", "auditory", "hot", "cold"];
%             bestneurons_persubset = cell(length(maxilabs), 2);
%             for i = 1:length(maxilabs)
%                 subset_temp = subset(app.zgrid, maxilabs(i));
%                 subset_temp = flatten(subset_temp);
%                 [~, tempsort] = sort(subset_temp.Zcorrel, 'descend');
%                 tempsort = tempsort(1:round(length(tempsort)/20));
%                 bestneurons_persubset{i, 1} = maxilabs(i);
%                 bestneurons_persubset{i, 2} = subset_temp.Zindex(tempsort);
%             end
%             pathcreated_bnps = fullfile('/home/ljp/Science/Hippolyte', 'bestneurons_persubset.mat');
%             save(pathcreated_bnps, 'bestneurons_persubset')
        end

        % Value changed function: 
        % Keywordsfordataset1usecommastoseparateEditField
        function Keywordsfordataset1usecommastoseparateEditFieldValueChanged(app, event)
            value = app.Keywordsfordataset1usecommastoseparateEditField.Value;
            % Spliting to find keywords:
            value = split(value, [" , ", " ,", ", ", ","]);
            % Looping to reduce ZBraingrid object:
            app.zset1 = app.zgrid;
            for i = 1:length(value)
                try
                    % Define dataset:
                    app.zset1 = subset(app.zset1, value(i));    
                    % Adapt sliders:
                    obj1 = flatten(app.zset1);
                    % Adapt number of neurons slider:
                    app.Dataset1Slider.Limits = [log10(1), log10(length(obj1.Zindex))];
                    app.nneu1 = floor(length(obj1.Zindex) / 1000);
                    app.Dataset1Slider.Value = log10(app.nneu1);
                    % Adapt absolut regressor value slider:
                    app.zcobj1 = sort(abs(obj1.Zcorrel), 'descend');
                    app.Dataset1Slider_2.Limits = [0, app.zcobj1(1)];
                    app.maval1 = app.zcobj1(app.nneu1); 
                    app.Dataset1Slider_2.Value = app.maval1;    
                    
                    % Storing value:
                    app.search1 = char(value);
                catch
                    msgbox('Subset to get is empty.')
                    % Replacing value:
                    app.Keywordsfordataset1usecommastoseparateEditField.Value = app.search1;
                end
            end
            % Message:
            msgbox('Dataset 1 successfully loaded!')
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: 
        % Keywordsfordataset2usecommastoseparateEditField
        function Keywordsfordataset2usecommastoseparateEditFieldValueChanged(app, event)
            value = app.Keywordsfordataset2usecommastoseparateEditField.Value;
            % Spliting to find keywords:
            value = split(value, [" , ", " ,", ", ", ","]);
            % Looping to reduce ZBraingrid object:
            app.zset2 = app.zgrid;
            for i = 1:length(value)
                try
                    % Define dataset:
                    app.zset2 = subset(app.zset2, value(i));  
                    % Adapt sliders:
                    obj2 = flatten(app.zset2);
                    % Adapt number of neurons slider:
                    app.Dataset2Slider.Limits = [log10(1), log10(length(obj2.Zindex))];
                    app.nneu2 = floor(length(obj2.Zindex) / 1000);
                    app.Dataset2Slider.Value = log10(app.nneu2);
                    % Adapt absolut regressor value slider:
                    app.zcobj2 = sort(abs(obj2.Zcorrel), 'descend');
                    app.Dataset2Slider_2.Limits = [0, app.zcobj2(1)];
                    app.maval2 = app.zcobj2(app.nneu2); 
                    app.Dataset2Slider_2.Value = app.maval2; 
                    
                    % Storing value:
                    app.search2 = char(value);
                catch
                    msgbox('Subset to get is empty.')
                    % Replacing value:
                    app.Keywordsfordataset2usecommastoseparateEditField.Value = app.search2;
                end
            end  
            % Message:
            msgbox('Dataset 2 successfully loaded!')      
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: 
        % PleasechoserepresentationoptionListBox
        function PleasechoserepresentationoptionListBoxValueChanged(app, event)
            value = app.PleasechoserepresentationoptionListBox.Value;
            % Replace app.ptool with selected option:
            possible_values = app.PleasechoserepresentationoptionListBox.Items;
            for i = 1:length(possible_values)
                if value == string(app.PleasechoserepresentationoptionListBox.Items{i})
                    app.ptool = i;
                    break
                end
            end
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: Dataset1Slider
        function Dataset1SliderValueChanged(app, event)
            value = app.Dataset1Slider.Value;
            % Change number of neurons and absolute regressor value:
            app.nneu1 = round(10^value);
            app.maval1 = app.zcobj1(app.nneu1);
            app.Dataset1Slider_2.Value = app.maval1;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: Dataset2Slider
        function Dataset2SliderValueChanged(app, event)
            value = app.Dataset2Slider.Value;
            % Change number of neurons and absolute regressor value:
            app.nneu2 = round(10^value);
            app.maval2 = app.zcobj2(app.nneu2);      
            app.Dataset2Slider_2.Value = app.maval2;   
            % Plot whatever needs to be plot:
            plotWrapper(app)   
        end

        % Value changed function: Dataset1Slider_2
        function Dataset1Slider_2ValueChanged(app, event)
            value = app.Dataset1Slider_2.Value;
            % Change number of neurons and absolute regressor value:
            app.maval1 = value;
            app.nneu1 = sum(app.zcobj1 >= app.maval1);
            if app.nneu1 == 0
                app.nneu1 = 1;
            end
            app.Dataset1Slider.Value = log10(app.nneu1);
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: Dataset2Slider_2
        function Dataset2Slider_2ValueChanged(app, event)
            value = app.Dataset2Slider_2.Value;
            % Change number of neurons and absolute regressor value:
            app.maval2 = value;
            app.nneu2 = sum(app.zcobj2 >= app.maval2);
            if app.nneu2 == 0
                app.nneu2 = 1;
            end
            app.Dataset2Slider.Value = log10(app.nneu2);  
            % Plot whatever needs to be plot:
            plotWrapper(app)          
        end

        % Value changed function: MarkersizeSlider
        function MarkersizeSliderValueChanged(app, event)
            value = app.MarkersizeSlider.Value;
            % Change marker size:
            app.mrksize = value;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Selection changed function: DensitytoplotforButtonGroup
        function DensitytoplotforButtonGroupSelectionChanged(app, event)
            selectedButton = app.DensitytoplotforButtonGroup.SelectedObject;
            % Change whichset property if needed:
            if app.whichset == 1 && selectedButton.Text == "Dataset 2"
                if ~isempty(app.zset2)
                    app.whichset = 2;
                else
                    msgbox('No subset to display.')
                    app.Dataset1Button.Value = true;
                end
            elseif app.whichset == 2 && selectedButton.Text == "Dataset 1"
                if ~isempty(app.zset1)
                    app.whichset = 1;
                else
                    msgbox('No subset to display.')
                    app.Dataset2Button.Value = true;
                end
            end
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: GaussianizeSlider
        function GaussianizeSliderValueChanged(app, event)
            value = app.GaussianizeSlider.Value;
            % Change gaussianity:
            if value == 0
                app.gauss = 0.001;
            else
                app.gauss = value;
            end
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Callback function
        function Isovalue1SliderValueChanged(app, event)
            value = app.Isovalue1Slider.Value;
            % Change isovalue 1:
            app.isoval1 = value;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Callback function
        function Isovalue2SliderValueChanged(app, event)
            value = app.Isovalue2Slider.Value;
            % Change isovalue 2:
            app.isoval2 = value;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Callback function
        function Isovalue3SliderValueChanged(app, event)
            value = app.Isovalue3Slider.Value;
            % Change isovalue 3:
            app.isoval3 = value;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: Quantileforisovalue1EditField
        function Quantileforisovalue1EditFieldValueChanged(app, event)
            value = app.Quantileforisovalue1EditField.Value;
            % Check value is between 0 and one:
            if value < 0 || value > 1
                msgbox('Please enter quantile between 0 and 1.')
                app.Quantileforisovalue1EditField.Value = 0;
            else
                % Else changing isovalue:
                if app.Dataset1Button.Value == true
                    app.isoval1 = quantile(app.zset1.Zcorrel, value);
                elseif app.Dataset2Button.Value == true
                    app.isoval1 = quantile(app.zset2.Zcorrel, value);
                end
                app.Isovalue1EditField.Value = app.isoval1;
            end
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: Quantileforisovalue2EditField
        function Quantileforisovalue2EditFieldValueChanged(app, event)
            value = app.Quantileforisovalue2EditField.Value;
            % Check value is between 0 and one:
            if value < 0 || value > 1
                msgbox('Please enter quantile between 0 and 1.')
                app.Quantileforisovalue2EditField.Value = 0;
            else
                % Else changing isovalue:
                if app.Dataset1Button.Value == true
                    app.isoval2 = quantile(app.zset1.Zcorrel, value);
                elseif app.Dataset2Button.Value == true
                    app.isoval2 = quantile(app.zset2.Zcorrel, value);
                end
                app.Isovalue2EditField.Value = app.isoval2;
            end          
            % Plot whatever needs to be plot:
            plotWrapper(app)  
        end

        % Value changed function: Quantileforisovalue3EditField
        function Quantileforisovalue3EditFieldValueChanged(app, event)
            value = app.Quantileforisovalue3EditField.Value;
            % Check value is between 0 and one:
            if value < 0 || value > 1
                msgbox('Please enter quantile between 0 and 1.')
                app.Quantileforisovalue3EditField.Value = 0;
            else
                % Else changing isovalue:
                if app.Dataset1Button.Value == true
                    app.isoval3 = quantile(app.zset1.Zcorrel, value);
                elseif app.Dataset2Button.Value == true
                    app.isoval3 = quantile(app.zset2.Zcorrel, value);
                end
                app.Isovalue3EditField.Value = app.isoval3;
            end
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Changing value for arotate and azoom:
            app.arotate = 1 * (app.arotate == 0);
            app.azoom = 0;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Changing value for arotate and azoom:
            app.arotate = 0;
            app.azoom = 1 * (app.azoom == 0);
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: ReinitializeButton
        function ReinitializeButtonPushed(app, event)
            % Reinitialize properties:
            app.aview = [-45, 45];
            view(app.UIAxes, app.aview)
            app.arotate = 0;
            app.azoom = 0;
            % Plot whatever needs to be plot:
            plotWrapper(app) 
            % Zoom reset:
            app.UIAxes.XLim = [0, app.alim(1)];
            app.UIAxes.YLim = [0, app.alim(2)];
            app.UIAxes.ZLim = [0, app.alim(3)];
        end

        % Button pushed function: XYButton
        function XYButtonPushed(app, event)
            % Saving view:
            app.aview = [-90, 90];
            view(app.UIAxes, app.aview)
            % Changing value for arotate and azoom:
            app.arotate = 0;
            app.azoom = 0;
            % Plot whatever needs to be plot:
            plotWrapper(app) 
        end

        % Button pushed function: XZButton
        function XZButtonPushed(app, event)
            % Saving view:
            app.aview = [180, 0];
            view(app.UIAxes, app.aview)
            % Changing value for arotate and azoom:
            app.arotate = 0;
            app.azoom = 0;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: YZButton
        function YZButtonPushed(app, event)
            % Saving view:
            app.aview = [-90, 0];
            view(app.UIAxes, app.aview)
            % Changing value for arotate and azoom:
            app.arotate = 0;
            app.azoom = 0;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: PlotscatteredoutlinesofzonesCheckBox
        function PlotscatteredoutlinesofzonesCheckBoxValueChanged(app, event)
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: PlotXYcontoursofzonesCheckBox
        function PlotXYcontoursofzonesCheckBoxValueChanged(app, event)
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: ChangecolorsetButton
        function ChangecolorsetButtonPushed(app, event)
            app.acolor = rand(size(app.acolor));
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: PlotthefollowingbrainzonesListBox
        function PlotthefollowingbrainzonesListBoxValueChanged(app, event)
            value = app.PlotthefollowingbrainzonesListBox.Value;
            app.regions = [];
            app.ListBox.Items = {};
            for i = 1:length(value)
                app.regions = cat(2, app.regions, str2double(value{i}(1:3)));
                app.ListBox.Items = [app.ListBox.Items, value{i}];
            end
            
            % Find new Outplots:
            newRegions(app)
            
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: PlotwholebrainButton
        function PlotwholebrainButtonPushed(app, event)
            % Enabling or not brain zones selection and replacing app.regions:
            if app.PlotthefollowingbrainzonesListBox.Enable == "off"
                app.PlotthefollowingbrainzonesListBox.Enable = 'on';
                app.regions = app.regions(1:end-5);
            else
                app.PlotthefollowingbrainzonesListBox.Enable = 'off';
                app.regions = cat(2, app.regions, [1, 94, 114, 260, 275]);
            end
            
            % Find new Outplots:
            newRegions(app)
            
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: PlotYZcontoursofzonesCheckBox
        function PlotYZcontoursofzonesCheckBoxValueChanged(app, event)
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: PlotXZcontoursofzonesCheckBox
        function PlotXZcontoursofzonesCheckBoxValueChanged(app, event)
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: SamecolorButton
        function SamecolorButtonPushed(app, event)
            app.acolor = app.acolor(1) * ones(size(app.acolor));
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ComparetwosetstoolsTab = uitab(app.TabGroup);
            app.ComparetwosetstoolsTab.Title = 'Compare two sets - tools';
            app.Dataset1SliderLabel = uilabel(app.ComparetwosetstoolsTab);
            app.Dataset1SliderLabel.Text = 'Dataset 1';
            app.Dataset1Slider = uislider(app.ComparetwosetstoolsTab);
            app.Dataset1Slider.Limits = [0 1];
            app.Dataset1Slider.ValueChangedFcn = createCallbackFcn(app, @Dataset1SliderValueChanged, true);
            app.Dataset2SliderLabel = uilabel(app.ComparetwosetstoolsTab);
            app.Dataset2SliderLabel.Text = 'Dataset 2';
            app.Dataset2Slider = uislider(app.ComparetwosetstoolsTab);
            app.Dataset2Slider.Limits = [0 1];
            app.Dataset2Slider.ValueChangedFcn = createCallbackFcn(app, @Dataset2SliderValueChanged, true);
            app.Dataset1Slider_2Label = uilabel(app.ComparetwosetstoolsTab);
            app.Dataset1Slider_2Label.Text = 'Dataset 1';
            app.Dataset1Slider_2 = uislider(app.ComparetwosetstoolsTab);
            app.Dataset1Slider_2.Limits = [0 1];
            app.Dataset1Slider_2.ValueChangedFcn = createCallbackFcn(app, @Dataset1Slider_2ValueChanged, true);
            app.Dataset2Slider_2Label = uilabel(app.ComparetwosetstoolsTab);
            app.Dataset2Slider_2Label.Text = 'Dataset2';
            app.Dataset2Slider_2 = uislider(app.ComparetwosetstoolsTab);
            app.Dataset2Slider_2.Limits = [0 1];
            app.Dataset2Slider_2.ValueChangedFcn = createCallbackFcn(app, @Dataset2Slider_2ValueChanged, true);
            app.MarkersizeSliderLabel = uilabel(app.ComparetwosetstoolsTab);
            app.MarkersizeSliderLabel.Text = 'Markersize';
            app.MarkersizeSlider = uislider(app.ComparetwosetstoolsTab);
            app.MarkersizeSlider.Limits = [0 30];
            app.MarkersizeSlider.ValueChangedFcn = createCallbackFcn(app, @MarkersizeSliderValueChanged, true);
            app.MarkersizeSlider.Value = 5;
            app.NumberofneuronsLogarithmicscaleLabel = uilabel(app.ComparetwosetstoolsTab);
            app.NumberofneuronsLogarithmicscaleLabel.Text = {'Number of neurons'; '(Logarithmic scale)'};
            app.AbsolutevalueofregressorLabel = uilabel(app.ComparetwosetstoolsTab);
            app.AbsolutevalueofregressorLabel.Text = 'Absolute value of regressor';
            app.CheckdensitiesofbothsetstoolsTab = uitab(app.TabGroup);
            app.CheckdensitiesofbothsetstoolsTab.Title = 'Check densities of both sets - tools';
            app.DensitytoplotforButtonGroup = uibuttongroup(app.CheckdensitiesofbothsetstoolsTab);
            app.DensitytoplotforButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DensitytoplotforButtonGroupSelectionChanged, true);
            app.DensitytoplotforButtonGroup.Title = 'Density to plot for:';
            app.Dataset1Button = uiradiobutton(app.DensitytoplotforButtonGroup);
            app.Dataset1Button.Text = 'Dataset 1';
            app.Dataset1Button.Value = true;
            app.Dataset2Button = uiradiobutton(app.DensitytoplotforButtonGroup);
            app.Dataset2Button.Text = 'Dataset 2';
            app.GaussianizeSliderLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.GaussianizeSliderLabel.Text = 'Gaussianize';
            app.GaussianizeSlider = uislider(app.CheckdensitiesofbothsetstoolsTab);
            app.GaussianizeSlider.Limits = [0 0.02];
            app.GaussianizeSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianizeSliderValueChanged, true);
            app.GaussianizeSlider.Value = 0.01;
            app.Quantileforisovalue1EditFieldLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.Quantileforisovalue1EditFieldLabel.Text = 'Quantile for isovalue 1';
            app.Quantileforisovalue1EditField = uieditfield(app.CheckdensitiesofbothsetstoolsTab, 'numeric');
            app.Quantileforisovalue1EditField.ValueChangedFcn = createCallbackFcn(app, @Quantileforisovalue1EditFieldValueChanged, true);
            app.Quantileforisovalue2EditFieldLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.Quantileforisovalue2EditFieldLabel.Text = 'Quantile for isovalue 2';
            app.Quantileforisovalue2EditField = uieditfield(app.CheckdensitiesofbothsetstoolsTab, 'numeric');
            app.Quantileforisovalue2EditField.ValueChangedFcn = createCallbackFcn(app, @Quantileforisovalue2EditFieldValueChanged, true);
            app.Quantileforisovalue3EditFieldLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.Quantileforisovalue3EditFieldLabel.Text = 'Quantile for isovalue 3';
            app.Quantileforisovalue3EditField = uieditfield(app.CheckdensitiesofbothsetstoolsTab, 'numeric');
            app.Quantileforisovalue3EditField.ValueChangedFcn = createCallbackFcn(app, @Quantileforisovalue3EditFieldValueChanged, true);
            app.Isovalue1EditFieldLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.Isovalue1EditFieldLabel.Enable = 'off';
            app.Isovalue1EditFieldLabel.Text = 'Isovalue 1';
            app.Isovalue1EditField = uieditfield(app.CheckdensitiesofbothsetstoolsTab, 'numeric');
            app.Isovalue1EditField.Enable = 'off';
            app.Isovalue2EditFieldLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.Isovalue2EditFieldLabel.Enable = 'off';
            app.Isovalue2EditFieldLabel.Text = 'Isovalue 2';
            app.Isovalue2EditField = uieditfield(app.CheckdensitiesofbothsetstoolsTab, 'numeric');
            app.Isovalue2EditField.Enable = 'off';
            app.Isovalue3EditFieldLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.Isovalue3EditFieldLabel.Enable = 'off';
            app.Isovalue3EditFieldLabel.Text = 'Isovalue 3';
            app.Isovalue3EditField = uieditfield(app.CheckdensitiesofbothsetstoolsTab, 'numeric');
            app.Isovalue3EditField.Enable = 'off';
            app.DFFregressorplottoolsTab = uitab(app.TabGroup);
            app.DFFregressorplottoolsTab.Title = 'DFF & regressor plot - tools';
            app.PlotbrainzonesTab = uitab(app.TabGroup);
            app.PlotbrainzonesTab.Title = 'Plot brain zones';
            app.PlotscatteredoutlinesofzonesCheckBox = uicheckbox(app.PlotbrainzonesTab);
            app.PlotscatteredoutlinesofzonesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotscatteredoutlinesofzonesCheckBoxValueChanged, true);
            app.PlotscatteredoutlinesofzonesCheckBox.Text = 'Plot scattered outlines of zones';
            app.PlotXYcontoursofzonesCheckBox = uicheckbox(app.PlotbrainzonesTab);
            app.PlotXYcontoursofzonesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotXYcontoursofzonesCheckBoxValueChanged, true);
            app.PlotXYcontoursofzonesCheckBox.Text = 'Plot X-Y contours of zones';
            app.ChangecolorsetButton = uibutton(app.PlotbrainzonesTab, 'push');
            app.ChangecolorsetButton.ButtonPushedFcn = createCallbackFcn(app, @ChangecolorsetButtonPushed, true);
            app.ChangecolorsetButton.Text = 'Change colorset';
            app.PlotthefollowingbrainzonesListBoxLabel = uilabel(app.PlotbrainzonesTab);
            app.PlotthefollowingbrainzonesListBoxLabel.Text = 'Plot the following brain zones';
            app.PlotthefollowingbrainzonesListBox = uilistbox(app.PlotbrainzonesTab);
            app.PlotthefollowingbrainzonesListBox.Items = {'Loading brain zones names'};
            app.PlotthefollowingbrainzonesListBox.Multiselect = 'on';
            app.PlotthefollowingbrainzonesListBox.ValueChangedFcn = createCallbackFcn(app, @PlotthefollowingbrainzonesListBoxValueChanged, true);
            app.PlotthefollowingbrainzonesListBox.Value = {'Loading brain zones names'};
            app.presscontroltopickmultipleLabel = uilabel(app.PlotbrainzonesTab);
            app.presscontroltopickmultipleLabel.Text = '(press control to pick multiple)';
            app.ListBox = uilistbox(app.PlotbrainzonesTab);
            app.ListBox.Items = {'Loading brain zones names'};
            app.ListBox.Enable = 'off';
            app.ListBox.Value = 'Loading brain zones names';
            app.PlotwholebrainButton = uibutton(app.PlotbrainzonesTab, 'push');
            app.PlotwholebrainButton.ButtonPushedFcn = createCallbackFcn(app, @PlotwholebrainButtonPushed, true);
            app.PlotwholebrainButton.Text = 'Plot whole brain';
            app.PlotYZcontoursofzonesCheckBox = uicheckbox(app.PlotbrainzonesTab);
            app.PlotYZcontoursofzonesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotYZcontoursofzonesCheckBoxValueChanged, true);
            app.PlotYZcontoursofzonesCheckBox.Text = 'Plot Y-Z contours of zones';
            app.PlotXZcontoursofzonesCheckBox = uicheckbox(app.PlotbrainzonesTab);
            app.PlotXZcontoursofzonesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotXZcontoursofzonesCheckBoxValueChanged, true);
            app.PlotXZcontoursofzonesCheckBox.Text = 'Plot X-Z contours of zones';
            app.SamecolorButton = uibutton(app.PlotbrainzonesTab, 'push');
            app.SamecolorButton.ButtonPushedFcn = createCallbackFcn(app, @SamecolorButtonPushed, true);
            app.SamecolorButton.Text = 'Same color';
            app.LoadZBraingridobjectButton = uibutton(app.UIFigure, 'push');
            app.LoadZBraingridobjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadZBraingridobjectButtonPushed, true);
            app.LoadZBraingridobjectButton.Text = 'Load ZBraingrid object';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'No ZBraingrid data provided yet'};
            app.ListofcommentsDropDownLabel = uilabel(app.UIFigure);
            app.ListofcommentsDropDownLabel.Text = 'List of comments';
            app.ListofcommentsDropDown = uidropdown(app.UIFigure);
            app.ListofcommentsDropDown.Items = {'No Zbraingrid data provided yet'};
            app.ListofcommentsDropDown.Value = 'No Zbraingrid data provided yet';
            app.ListofkeywordsDropDownLabel = uilabel(app.UIFigure);
            app.ListofkeywordsDropDownLabel.Text = 'List of keywords';
            app.ListofkeywordsDropDown = uidropdown(app.UIFigure);
            app.ListofkeywordsDropDown.Items = {'No ZBraingrid data provided yet'};
            app.ListofkeywordsDropDown.Value = 'No ZBraingrid data provided yet';
            app.Keywordsfordataset1usecommastoseparateEditFieldLabel = uilabel(app.UIFigure);
            app.Keywordsfordataset1usecommastoseparateEditFieldLabel.Text = {'Keywords for dataset 1'; '(use commas to separate) '};
            app.Keywordsfordataset1usecommastoseparateEditField = uieditfield(app.UIFigure, 'text');
            app.Keywordsfordataset1usecommastoseparateEditField.ValueChangedFcn = createCallbackFcn(app, @Keywordsfordataset1usecommastoseparateEditFieldValueChanged, true);
            app.Keywordsfordataset2usecommastoseparateEditFieldLabel = uilabel(app.UIFigure);
            app.Keywordsfordataset2usecommastoseparateEditFieldLabel.Text = {'Keywords for dataset 2'; '(use commas to separate)'};
            app.Keywordsfordataset2usecommastoseparateEditField = uieditfield(app.UIFigure, 'text');
            app.Keywordsfordataset2usecommastoseparateEditField.ValueChangedFcn = createCallbackFcn(app, @Keywordsfordataset2usecommastoseparateEditFieldValueChanged, true);
            app.PleasechoserepresentationoptionLabel = uilabel(app.UIFigure);
            app.PleasechoserepresentationoptionLabel.Text = 'Please chose representation option:';
            app.PleasechoserepresentationoptionListBox = uilistbox(app.UIFigure);
            app.PleasechoserepresentationoptionListBox.Items = {'Compare two sets', 'Check densities of both sets', 'DFF & regressor plot'};
            app.PleasechoserepresentationoptionListBox.ValueChangedFcn = createCallbackFcn(app, @PleasechoserepresentationoptionListBoxValueChanged, true);
            app.PleasechoserepresentationoptionListBox.Value = 'Check densities of both sets';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.RotateButton = uibutton(app.ControlsPanel, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.XZButton = uibutton(app.ControlsPanel, 'push');
            app.XZButton.ButtonPushedFcn = createCallbackFcn(app, @XZButtonPushed, true);
            app.XZButton.Text = 'X-Z';
            app.XYButton = uibutton(app.ControlsPanel, 'push');
            app.XYButton.ButtonPushedFcn = createCallbackFcn(app, @XYButtonPushed, true);
            app.XYButton.Text = 'X-Y';
            app.YZButton = uibutton(app.ControlsPanel, 'push');
            app.YZButton.ButtonPushedFcn = createCallbackFcn(app, @YZButtonPushed, true);
            app.YZButton.Text = 'Y-Z';
            app.ZoomButton = uibutton(app.ControlsPanel, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Text = 'Zoom';
            app.ReinitializeButton = uibutton(app.ControlsPanel, 'push');
            app.ReinitializeButton.ButtonPushedFcn = createCallbackFcn(app, @ReinitializeButtonPushed, true);
            app.ReinitializeButton.Text = 'Reinitialize';
        end
    end

    methods (Access = public)

        % Construct app
        function app = visualizeAppReg

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end